classdef BPlaneVisualizationRev09d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        DevFunctions                   matlab.ui.container.Menu
        DevFx1_ExportValues            matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        InterplanetaryTransferTab      matlab.ui.container.Tab
        InterplanetaryTransferFunctionInputsPanel  matlab.ui.container.Panel
        MaxLabel                       matlab.ui.control.Label
        MinLabel                       matlab.ui.control.Label
        ROminIncl                      matlab.ui.control.NumericEditField
        ROmaxIncl                      matlab.ui.control.NumericEditField
        ROminArgp                      matlab.ui.control.NumericEditField
        ROmaxArgp                      matlab.ui.control.NumericEditField
        ChooseAngleButtonGroup         matlab.ui.container.ButtonGroup
        ArgumentofPeriapsisButton      matlab.ui.control.RadioButton
        RightAscensionofAscendingNodeButton  matlab.ui.control.RadioButton
        InclinationButton              matlab.ui.control.RadioButton
        ArrivalOrbitParametersLabel    matlab.ui.control.Label
        AltitudePeriapsisEditField     matlab.ui.control.NumericEditField
        AltitudePeriapsisEditFieldLabel  matlab.ui.control.Label
        kmLabel_2                      matlab.ui.control.Label
        degreesLabel_6                 matlab.ui.control.Label
        AngleValueEditField            matlab.ui.control.NumericEditField
        AngleValueEditFieldLabel       matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        LaunchDateDatePicker           matlab.ui.control.DatePicker
        LaunchDateDatePickerLabel      matlab.ui.control.Label
        AdditionaldaysEditField        matlab.ui.control.NumericEditField
        AdditionaldaysEditFieldLabel   matlab.ui.control.Label
        daysLabel                      matlab.ui.control.Label
        daysLabel_2                    matlab.ui.control.Label
        TimeofFlightTOFEditField       matlab.ui.control.NumericEditField
        TimeofFlightTOFEditFieldLabel  matlab.ui.control.Label
        DeparturePlanetDropDown        matlab.ui.control.DropDown
        DeparturePlanetDropDownLabel   matlab.ui.control.Label
        ArrivalPlanetDropDown          matlab.ui.control.DropDown
        ArrivalPlanetDropDownLabel     matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        ArrivalOrbitTab                matlab.ui.container.Tab
        ReadoutPanel                   matlab.ui.container.Panel
        EccentricityEditField          matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel     matlab.ui.control.Label
        TrueAnomalyEditField           matlab.ui.control.NumericEditField
        TrueAnomalyEditFieldLabel      matlab.ui.control.Label
        AngularMomentumEditField       matlab.ui.control.NumericEditField
        AngularMomentumEditFieldLabel  matlab.ui.control.Label
        degLabel_4                     matlab.ui.control.Label
        km2sLabel                      matlab.ui.control.Label
        ControlVariablesPanel          matlab.ui.container.Panel
        ArgumentofPerigeewEditField    matlab.ui.control.NumericEditField
        ArgumentofPerigeewEditFieldLabel  matlab.ui.control.Label
        degLabel                       matlab.ui.control.Label
        RAANEditField                  matlab.ui.control.NumericEditField
        RAANEditFieldLabel             matlab.ui.control.Label
        degLabel_2                     matlab.ui.control.Label
        kmLabel                        matlab.ui.control.Label
        degLabel_3                     matlab.ui.control.Label
        AltitudeofPeriapsisEditField2  matlab.ui.control.NumericEditField
        AltitudeofPeriapsisEditFieldLabel  matlab.ui.control.Label
        InclinationEditField           matlab.ui.control.NumericEditField
        InclinationEditFieldLabel      matlab.ui.control.Label
        ModifyVelocityAngelsPanel      matlab.ui.container.Panel
        degAz_beta                     matlab.ui.control.NumericEditField
        degEditField_2Label_2          matlab.ui.control.Label
        degEl_el                       matlab.ui.control.NumericEditField
        degEditFieldLabel_2            matlab.ui.control.Label
        Az_betaSliderLabel             matlab.ui.control.Label
        Az_betaSlider                  matlab.ui.control.Slider
        El_Slider                      matlab.ui.control.Slider
        El_Label                       matlab.ui.control.Label
        AdjustValuebypercentLabel      matlab.ui.control.Label
        ModifySphereofInfluenceEntryLocationPanel  matlab.ui.container.Panel
        AdjustValuebypercentLabel_2    matlab.ui.control.Label
        degDeclin                      matlab.ui.control.NumericEditField
        degEditField_2Label            matlab.ui.control.Label
        degRightAsc                    matlab.ui.control.NumericEditField
        degEditFieldLabel              matlab.ui.control.Label
        DeclinationSlider              matlab.ui.control.Slider
        DeclinationSliderLabel         matlab.ui.control.Label
        RightAscSlider                 matlab.ui.control.Slider
        RightAscSliderLabel            matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ScratchTab                     matlab.ui.container.Tab
        ROminRAAN                      matlab.ui.control.NumericEditField
        ROmaxRAAN                      matlab.ui.control.NumericEditField
        kmsLabel                       matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        V_infEditField                 matlab.ui.control.NumericEditField
        V_infEditFieldLabel            matlab.ui.control.Label
        VelocitykmsPlanetcentricLabel  matlab.ui.control.Label
        InterPlanetaryTravelLabel      matlab.ui.control.Label
        SampleHyperbolicOrbitLabel     matlab.ui.control.Label
        TestCase01Button               matlab.ui.control.Button
        TestCase02Button               matlab.ui.control.Button
        TestCase03Button               matlab.ui.control.Button
        TestCase04Button               matlab.ui.control.Button
        kmsVelocityMag                 matlab.ui.control.NumericEditField
        kmsEditFieldLabel              matlab.ui.control.Label
        VelocityMagSlider              matlab.ui.control.Slider
        VelocityMagSliderLabel         matlab.ui.control.Label
        ModifyMagnitudeOfVelocityplanetcentricbypercentLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        OrbitVisualizerLabel           matlab.ui.control.Label
    end

    properties (Access = public);
        % Tab 1 Sections. 
        launchdate;      
        departplanet;% arrivalplanet; % unneeded. app.planet is the default arrival
        planet
        tofdays;

        % Plot Variables.
        pplot   ;% Planet Plot
        splot   ;% Sphere of Influence Plot 
        eplot   ;% point-of-ENTRY plot
        oiplot  ;% Orbit, Initial Plot
        oilplot ;% Orbit, Initial Location, Plot
        ROI     ;% Radius of Influence
        r_ECI_arr_p; % Orbit near arrival planet

        % Point of Entry Location
        r_entry; % Define r_entry as an app property
        rightasc;% Right Ascension of Ascending Node, Formerly "Phi", Degrees east of the vernal equinox. Geocentric.
        declin  ;% Declination,                       Formerly "Theta"

        % Velocity Orientation
        v       ;% magnitude of velocity, km/s
        az      ;% Azimuth   angle, degrees
        el      ;% Elevation angle, degrees
        rot_angle;% Angle of rotation. deg.
        rot_axis; % Axis of rotation. 1 or 3
            
        % Initial value variable used to hold initial value. Used as
        % a reference for slider variables to shift from. 
        rightasc_initial ;      az_initial; 
        declin_initial   ;      el_initial; 
        v_initial;

        mu      ;%
        Planet  ;% Planet use to look up various properties
        a       ;% Semi Major Axis, km. sma
        e       ;% Eccentricity
        i
        RAAN    ;
        argp    ;% AOP
        TA      ;% True anomaly, Deg
        rp      ;% Radius of Periapsis
        zp      ;% Altitude of Periapsis

        % B Plane Parameters
        BdotR   ;% radial component of the B Vector
        BdotT   ;% Tangential component.
        BMag    ;% Magnitude
        vinf    ;% Velocity, Entering SOI.
        bplot   ;
        p1      ; %R Unit Vector
        p2      ; %T
        p3      ; %S
        p4      ; %B Vector
        ECI     ;% Equatorial Plane
        
        interplanetaryvarin; % Controls input to interplanetary calculations (RAAN vs. ARGP vs. i)

    end
    
    methods (Access = private);
          
        function Run_TestCase_1(app);% Random Hyperbolic Oribt Case.

             % Constants
            app.Planet = app.ArrivalPlanetDropDown.Value;
            planetparam = func_Primary_Parameters_RevC(app.Planet);
            app.mu  = planetparam.mu;
            app.ROI = planetparam.r_SOI;
            RE = planetparam.RE;
            
            % Case 1: Hyperbolic orbit.
            app.rp   = RE+1000; % Rp set to 500 km greater the equatorial radius
            app.zp   = app.rp - RE; % Altitude of Periapsis.
            app.e    = 1.10;
            app.a    = app.rp/(app.e-1);   %SemiMajor Axis. Positive convention is preferred.
            app.i    = 20;             
            app.argp = 10; % Argument of Perigee, is sometimes just 'w'
            app.RAAN = 30; % Right Ascension.
            
            h = sqrt(app.a*app.mu*abs(1-app.e^2) ); % Eq. 2.71.
            app.TA  = -acosd( (1/app.e)*((h^2/app.mu/app.ROI) -1 ));
            
            % fprintf('Hyperbolic Orbit Case. Starting Conditions \n')
            [app.r_entry,v_I,~,~] = func_OEtoRV(app.e,app.a,app.i,app.argp,app.RAAN,app.TA,app.mu); % Solve for R and V to satisfy desired orbit
            [ra, dec]             = ra_and_dec_from_r(app.r_entry);                    % Calculate RAAN and Declination from Position
            [azm, elev ]          = func_GCI_to_SEZ_velort_RevB(dec, ra, v_I);       % Convert Coord. Sys and Redefine Velocity Orientation

            % Initializing GUI's Controller Values
            app.RightAscSlider.Value    = 0;        app.DeclinationSlider.Value = 0;    % Slider Values                           
            app.rightasc_initial        = ra;       app.declin_initial          = dec;  % Log for later deviations   
            app.rightasc                = ra;       app.declin                  = dec;  % Working Value

            app.El_Slider.Value       = 0;          app.Az_betaSlider.Value       = 0;  % Slider Values
            app.el_initial              = elev;     app.az_initial                = azm;% Log for later deviations
            app.el                      = elev;     app.az                        = azm;% Working Value

            app.VelocityMagSlider.Value = 0;

            app.XEditField.Value = v_I(1); % adding back in for troubleshooting
            app.YEditField.Value = v_I(2);  
            app.ZEditField.Value = v_I(3); 
            app.v         = v_I;
            app.v_initial = app.v;
            Plot_Planet(app)    % Plot Initial Conditions
            Plot_Orbit(app)     % Plot the Initial Orbit
            Plot_BPlane(app)    % Plot B Plane Items
            
            % Update Displays
            Update_Displays(app)

                % Formatting.
                % "Far View"                                % "Near View"
                view(app.UIAxes,135,30);                    view(app.UIAxes_2,135,30);
                                                            lim2 = 5*RE;    % Limit Criteria: Max Orbit Position and factor of Planet Radius
                app.UIAxes.XLim = [-app.ROI app.ROI ];      app.UIAxes_2.XLim = [-lim2 lim2 ];
                app.UIAxes.YLim = [-app.ROI app.ROI ];      app.UIAxes_2.YLim = [-lim2 lim2 ];
                app.UIAxes.ZLim = [-app.ROI app.ROI ];      app.UIAxes_2.ZLim = [-lim2 lim2 ];
                
        end

      % function Run_TestCase_2(app); % Incli. or Z_peri updated.
            %         clc,  cla(app.UIAxes), cla(app.UIAxes_2) 
            %         % Constants
            %         app.Planet = app.ArrivalPlanetDropDown.Value;
            %         [app.ROI] = func_ROI(app.Planet)
            %         [RE,~,app.mu,~,~,~,~] = func_Primary_Parameters(app.Planet);
            %         % [app.e,app.a,app.i,w,Om,app.TA,gam,h] = func_RVtoOE(r,app.v,app.mu) % r is currently undefined.!!
            % 
            %         app.zp = 200; 
            %         app.rp   = app.zp+RE % Rp set to 500 km greater the equatorial radius
            %         app.zp   = app.rp - RE % Altitude of Periapsis.
            % 
            %         app.a    = app.rp/(app.e-1);   %SemiMajor Axis. Positive convention is preferred.        
            %         % app.argp = 10; % Argument of Perigee, is sometimes just 'w'
            %         % app.RAAN = 30; % Right Ascension.
            % 
            %         h = sqrt(app.a*app.mu*abs(1-app.e^2) ); % Eq. 2.71.
            %         whos   h
            %         app.mu
            %         app.e
            %         app.ROI
            %         app.TA  = -acosd( (1/app.e)*((h^2/app.mu/app.ROI) -1 ));
            % 
            %         % fprintf('Hyperbolic Orbit Case. Starting Conditions \n')
            %         [app.r_entry,v_I,~,~] = func_OEtoRV(app.e,app.a,app.i,app.argp,app.RAAN,app.TA,app.mu); % Solve for R and V to satisfy desired orbit
            %         [ra, dec]             = ra_and_dec_from_r(app.r_entry);                    % Calculate RAAN and Declination from Position
            %         [azm, elev ]          = func_GCI_to_SEZ_velort_RevB(dec, ra, v_I);       % Convert Coord. Sys and Redefine Velocity Orientation
            % 
            %         % Initializing GUI's Controller Values
            %         app.RightAscSlider.Value    = 0;        app.DeclinationSlider.Value = 0;    % Slider Values                           
            %         app.rightasc_initial        = ra;       app.declin_initial          = dec;  % Log for later deviations   
            %         app.rightasc                = ra;       app.declin                  = dec;  % Working Value
            % 
            %         app.El_Slider.Value       = 0;          app.Az_betaSlider.Value       = 0;  % Slider Values
            %         app.el_initial              = elev;     app.az_initial                = azm;% Log for later deviations
            %         app.el                      = elev;     app.az                        = azm;% Working Value
            %             % Note Display updates are now performed in fx "Update_Displays(app)
            %             % Function need only assign values to app Properties. 
            %             % i.e. variables of type: "app.example"
            %         app.v         = v_I;
            %         Plot_Planet(app)    % Plot Initial Conditions
            %         Plot_Orbit(app)     % Plot the Initial Orbit
            %         Plot_BPlane(app)    % Plot B Plane Items
            %     end
        
        function Run_TestCase_3(app); % Update due to Position (R) changes

            clc,  cla(app.UIAxes), cla(app.UIAxes_2) 
            % Constants
            app.Planet = app.ArrivalPlanetDropDown.Value;
            [app.ROI] = func_ROI(app.Planet)
            [RE,~,app.mu,~,~,~,~] = func_Primary_Parameters(app.Planet);

            
            % fprintf('Hyperbolic Orbit Case. Starting Conditions \n')
            [app.r_entry,v_I,~,~] = func_OEtoRV(app.e,app.a,app.i,app.argp,app.RAAN,app.TA,app.mu); % Solve for R and V to satisfy desired orbit
            [ra, dec]             = ra_and_dec_from_r(app.r_entry);                    % Calculate RAAN and Declination from Position
            [azm, elev ]          = func_GCI_to_SEZ_velort_RevB(dec, ra, v_I);       % Convert Coord. Sys and Redefine Velocity Orientation

            % Initializing GUI's Controller Values
            app.RightAscSlider.Value    = 0;        app.DeclinationSlider.Value = 0;    % Slider Values                           
            app.rightasc_initial        = ra;       app.declin_initial          = dec;  % Log for later deviations   
            app.rightasc                = ra;       app.declin                  = dec;  % Working Value

            app.El_Slider.Value       = 0;          app.Az_betaSlider.Value       = 0;  % Slider Values
            app.el_initial              = elev;     app.az_initial                = azm;% Log for later deviations
            app.el                      = elev;     app.az                        = azm;% Working Value
                % Note Display updates are now performed in fx "Update_Displays(app)
                % Function need only assign values to app Properties. 
                % i.e. variables of type: "app.example"
            app.v         = v_I;
            Plot_Planet(app)    % Plot Initial Conditions
            Plot_Orbit(app)     % Plot the Initial Orbit
            Plot_BPlane(app)    % Plot B Plane Items
            % 
        end

                function Run_TestCase_4(app);% Start the chain with the interplanetary code. Default Launch
            app.departplanet = 'Earth';
            app.Planet = 'Mars';
            
            app.DeparturePlanetDropDown.Value = app.departplanet ;
            app.ArrivalPlanetDropDown.Value   = app.Planet; 
            app.LaunchDateDatePicker.Value    = datetime(2013,11,18,20,26,24.315);
            app.TimeofFlightTOFEditField.Value=295; % Days.
            app.AdditionaldaysEditField.Value = 0;
            app.ChooseAngleButtonGroup == app.InclinationButton;
            app.interplanetaryvarin = 'i';
            app.AngleValueEditField.Value = 25;  % Degrees, 
            app.AltitudePeriapsisEditField.Value    = 75; % km
            app.zp = app.AltitudePeriapsisEditField.Value;
            Update_InterplanteryTransfer(app);

        end


        function Plot_BPlane(app);
            hold(app.UIAxes, 'on');             hold(app.UIAxes_2, 'on');
             [app.bplot, app.p1,app.p2,app.p3,app.p4,app.ECI, app.BdotR, app.BdotT, app.BMag,app.vinf]      =...
             plot_BPlane_RevB3(app.r_entry,app.v, app.Planet,app.UIAxes, app.UIAxes_2);

             % Update Displays.
             app.V_infEditField.Value               = app.vinf;
             Update_Displays(app);
        end

        function Plot_Orbit(app);
            hold(app.UIAxes, 'on'); 
            hold(app.UIAxes_2, 'on');
            [app.oiplot, app.oilplot, oiplot2, oilplot2,r_new] =plot_Orbit_RevD(app.r_entry,app.v,app.Planet,app.UIAxes, app.UIAxes_2);
            app.r_ECI_arr_p = r_new;
        end
        
        function Plot_Planet(app)
            [app.pplot, app.splot, app.eplot, app.r_entry]     =plot_Planet_RevC(app.Planet,app.declin,app.rightasc,app.UIAxes, app.UIAxes_2);
        end
        
        function Update_Plot_EntryPoint(app)
            [RE,~,app.mu,~,~,~,~] = func_Primary_Parameters(app.Planet);
            cla(app.UIAxes);
            cla(app.UIAxes_2) ;
            
            [app.r_entry] = Update_R_Entry(app.ROI,app.declin,app.rightasc); % Calculate new R from new Angles.
                coe = coe_from_sv(app.r_entry,app.v,app.mu);                 % Derive new COE from new R and V
                app.TA = coe(6)*180/pi;                                      % Update app.TA
            app.pplot = plot3(app.UIAxes,app.r_entry(1),app.r_entry(2),app.r_entry(3), 'Marker', 'o','MarkerEdgeColor','k','MarkerSize',9 );
            Plot_Orbit(app)  ;
            Plot_BPlane(app);
            Plot_Planet(app);
            app.e = coe(2);
            app.a = coe(7);
            app.i = coe(4)*180/pi;
            app.argp = coe(5)*180/pi;
            app.RAAN = coe(3)*180/pi;
            app.zp = abs(app.a)*(app.e -1) - RE;
            Update_Displays(app);

        end
        
        function Update_Velocity_Orient(app)
            %       rot_axis = 1: Rotated about S for Elevation Angle Change
            %                = 3:               Z for Azimuth   Angle Change
            [ app.v] = func_change_velort_RevB(app.rightasc,app.declin, app.rot_axis,app.rot_angle, app.v);
        end
        
        function Update_Plot_Velocity(app)
            % Updates Plots for changes in Velocity
            % axes(app.UIAxes);   delete(app.oilplot); delete(app.oiplot); % Delete the orbit plots for each figure
            cla(app.UIAxes);
            cla(app.UIAxes_2) ;
            Plot_Orbit(app);
            Plot_BPlane(app);
            Plot_Planet(app);
            % Add B Plane parameters later.
        end
        
        
        function Update_Displays(app);
            planetparam = func_Primary_Parameters_RevC(app.Planet);
            app.mu  = planetparam.mu;
            app.ROI = planetparam.r_SOI;
            RE = planetparam.RE;
         % Updates digital read outs on the App                       
            app.degRightAsc.Value       = app.rightasc;       
            app.degDeclin.Value         = app.declin;  % Readout Display

            app.degEl_el.Value          = app.el;     
            app.degAz_beta.Value        = app.az;% Readout Display
        
            % Initializing Other Display Fields
            app.XEditField.Value = app.v(1); 
            app.YEditField.Value = app.v(2);  
            app.ZEditField.Value = app.v(3); 
            app.kmsVelocityMag.Value = norm(app.v);
            app.AltitudeofPeriapsisEditField2.Value = app.zp;
            % app.AltitudeofPeriapsisEditField2.Value = app.zp;
            app.InclinationEditField.Value         = app.i;
            app.V_infEditField.Value               = app.vinf;
            app.RAANEditField.Value                = app.RAAN;
            app.EccentricityEditField.Value        = app.e;               
            app.ArgumentofPerigeewEditField.Value  = app.argp;
            % app.AngularMomentumEditField.Value     = h;
            app.TrueAnomalyEditField.Value         = app.TA;              
        end


        function Update_InterplanteryTransfer(app);
            % function that calls the .m file.
            % This method of implementation allows for similiar version
            % control.
            cla(app.UIAxes);  cla(app.UIAxes_2 );  
            cla(app.UIAxes3); % clear the interplanetary tab.
            planetparam = func_Primary_Parameters_RevC(app.Planet);
            app.mu  = planetparam.mu;
            app.ROI = planetparam.r_SOI;
            RE = planetparam.RE;
        [v2_vec, JD_Dep, JD_Arr] = func_ARO3090_Interplanetary_Transfer_Rev3(app.DeparturePlanetDropDown.Value,app.ArrivalPlanetDropDown.Value,app.LaunchDateDatePicker.Value,...
                            app.AdditionaldaysEditField.Value,app.TimeofFlightTOFEditField.Value,app.UIAxes3 );

        if strcmp(app.departplanet, 'Earth' );
            rotma0 = eye(3);
        else
            [alpha0, delta0, W0] = getRotationalElements(app.departplanet, JD_Dep);
            [ rotma0] = transformToPlanetaryFrameRevA( alpha0, delta0);
        end
        if strcmp(app.Planet , 'Earth' ) ;
            rotma1 = eye(3);
        else
            [alpha1, delta1, W1] = getRotationalElements(app.Planet      , JD_Arr);
            [ rotma1] = transformToPlanetaryFrameRevA( alpha1, delta1);
        end
            
            v2_vec = rotma1*rotma0'*v2_vec
            
            
            % [tilt,drotm] = func_obliq(app.DeparturePlanetDropDown.Value)% tilt func to turn depplanet normal to eclipcitc. 
            % [tilt,arotm] = func_obliq(app.ArrivalPlanetDropDown.Value) % tilt func to turn normal to arr planet tilt.
            % arrivalv= arotm*drotm'*v2_vec; % rotated to arrival planet* rotate normal to ecliptic * velocity
            
            input = app.interplanetaryvarin;  % 'RAAN' or 'i' or 'ARGP'
             % [coe,r_vec_SOI_Entry_Ia]  = func_copy_MAIN_Planetary_Approach(v2_vec,app.AltitudePeriapsisEditField.Value , app.AngleValueEditField.Value,app.Planet);
             % [coe,r_vec_SOI_Entry_Ia]  = func_copy_MAIN_Planetary_Approach_RevB(v2_vec,app.AltitudePeriapsisEditField.Value , app.AngleValueEditField.Value,app.Planet,input);
             [coe,output]  = func_copy_MAIN_Planetary_Approach_RevC2(v2_vec,app.AltitudePeriapsisEditField.Value, app.AngleValueEditField.Value,app.Planet,input);
            app.e = coe(1);         app.argp = coe(4);
            app.a = coe(2);         app.RAAN = coe(5);
            app.i = coe(3);         app.TA   = coe(6);  h = coe(7);
        
            app.ROminIncl.Value = output.i_range(1);
            app.ROmaxIncl.Value = output.i_range(2);
            app.ROminArgp.Value = output.argp_range(1);
            app.ROmaxArgp.Value = output.argp_range(2);
            % [app.oiplot, app.oilplot, oiplot2, oilplot2,r_new] =plot_Orbit_RevD(app.r_entry,app.v,app.Planet,app.UIAxes, app.UIAxes_2);
            [app.r_entry,v_I,~,~] = func_OEtoRV(app.e,app.a,app.i,app.argp,app.RAAN,app.TA,app.mu); % Solve for R and V to satisfy desired orbit

            [ra, dec]             = ra_and_dec_from_r(app.r_entry);                    % Calculate RAAN and Declination from Position
            [azm, elev ]          = func_GCI_to_SEZ_velort_RevB(dec, ra, v_I);       % Convert Coord. Sys and Redefine Velocity Orientation

            % Initializing GUI's Controller Values
            app.RightAscSlider.Value    = 0;        app.DeclinationSlider.Value = 0;    % Slider Values                           
            app.rightasc_initial        = ra;       app.declin_initial          = dec;  % Log for later deviations   
            app.rightasc                = ra;       app.declin                  = dec;  % Working Value

            app.El_Slider.Value       = 0;          app.Az_betaSlider.Value       = 0;  % Slider Values
            app.el_initial              = elev;     app.az_initial                = azm;% Log for later deviations
            app.el                      = elev;     app.az                        = azm;% Working Value
            app.VelocityMagSlider.Value = 0;
            app.XEditField.Value = v_I(1); % adding back in for troubleshooting
            app.YEditField.Value = v_I(2);  
            app.ZEditField.Value = v_I(3); 
            app.v         = v_I;
            app.v_initial = app.v;
            
            Plot_Planet(app);   % Plot Initial Conditions
            Plot_Orbit(app);    % Plot the Initial Orbit
            Plot_BPlane(app);  % Plot B Plane Items

            % Update Displays
            Update_Displays(app);
            app.AngularMomentumEditField.Value = h;
            app.AltitudeofPeriapsisEditField2.Value = app.zp;
                % Formatting.
                % "Far View"                                % "Near View"
                view(app.UIAxes,135,30);                    view(app.UIAxes_2,135,30);
                                                            lim2 = 5*RE;    % Limit Criteria: Max Orbit Position and factor of Planet Radius
                app.UIAxes.XLim = [-app.ROI app.ROI ];      app.UIAxes_2.XLim = [-lim2 lim2 ];
                app.UIAxes.YLim = [-app.ROI app.ROI ];      app.UIAxes_2.YLim = [-lim2 lim2 ];
                app.UIAxes.ZLim = [-app.ROI app.ROI ];      app.UIAxes_2.ZLim = [-lim2 lim2 ];

        end


        function Run_UseCase_01(app)
            % app.ChooseAngleButtonGroup == app.InclinationButton
            % app.i = app.AngleValueEditField.Value;

            selectedButton = app.ChooseAngleButtonGroup.SelectedObject;
            if selectedButton == app.ArgumentofPeriapsisButton
                app.interplanetaryvarin ='ARGP';
                app.argp = app.AngleValueEditField.Value;
            elseif selectedButton == app.RightAscensionofAscendingNodeButton
                app.interplanetaryvarin ='RAAN';
                app.RAAN = app.AngleValueEditField.Value;
            elseif selectedButton == app.InclinationButton
                app.interplanetaryvarin ='i';
                app.i = app.AngleValueEditField.Value;
            else
                fprintf('Error Occured: Radio Button'), return
            end

            % app.zp = app.AltitudePeriapsisEditField.Value;
            Update_InterplanteryTransfer(app);
        end
    end % Methods End
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;  cla(app.UIAxes); cla(app.UIAxes_2); 
            app.ArrivalPlanetDropDown.Value = "Mars";
            % Run_TestCase_1(app)
            Run_TestCase_4(app);
                            
        end

        % Value changed function: ArrivalPlanetDropDown
        function DropDown_Planet_ValueChanged(app, event)
            clc;
            app.Planet = [];
            app.Planet = app.ArrivalPlanetDropDown.Value;
            app.ROI = []; % Fixed bug that cause ROI to become M x N cell.
            cla(app.UIAxes); cla(app.UIAxes_2);
            Run_TestCase_1(app);
            Update_Displays(app);

        end

        % Value changed function: RightAscSlider
        function Slider_RightAsc_ValueChanged(app, event)
            app.rightasc = (app.RightAscSlider.Value + 1)*(app.rightasc_initial); %  
            app.degRightAsc.Value       = app.rightasc;       % Readout Field  
            Update_Plot_EntryPoint(app);
            
            Update_Displays(app);
            
        end

        % Value changed function: DeclinationSlider
        function Slider_Declination_ValueChanged(app, event)
            app.declin = (app.DeclinationSlider.Value+1)*(app.declin_initial);
            app.degDeclin.Value         = app.declin;   % Readout Field
            Update_Plot_EntryPoint(app);
            Update_Displays(app);
            
        end

        % Value changed function: VelocityMagSlider
        function Slider_VelocityMag_ValueChanged(app, event)
            value = app.VelocityMagSlider.Value;
            app.v = (value+1)*(app.v_initial  );          % New Elevation angle.
            % whos app.v
            app.XEditField.Value = app.v(1); 
            app.YEditField.Value = app.v(2);  
            app.ZEditField.Value = app.v(3); 
            Update_Plot_Velocity(app)
            Update_Displays(app)
        end

        % Value changed function: El_Slider
        function Slider_Elevation_ValueChanged(app, event)
             value = app.El_Slider.Value;                 % Percent to Shift from baseline Elevation value.
             app.el = (value+1)*(app.el_initial  );       % New Elevation angle.
             app.degEl_el.Value         = app.el;     % Readout Field
             app.rot_angle = app.el - app.el_initial;       % Angle Difference.
             app.rot_axis =1;

             Update_Velocity_Orient(app);
             Update_Plot_Velocity(app);
             Update_Displays(app);


        end

        % Value changed function: Az_betaSlider
        function Slider_Azimuth_ValueChanged(app, event)
            value = app.Az_betaSlider.Value;
            app.az = (value+1)*(app.az_initial  );          % New Elevation angle.
            app.degAz_beta.Value      = app.az;             % Readout Field
            app.rot_angle = app.az - app.az_initial;            % Angle Difference.
            app.rot_axis =3;
            Update_Velocity_Orient(app);
            Update_Plot_Velocity(app);
            Update_Displays(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            startupFcn(app);
            fprintf('\n\nApp Reset\n\n')
        end

        % Value changed function: InclinationEditField
        function Numeric_Inclination_ValueChanged(app, event)
            app.i = app.InclinationEditField.Value;
            app.ChooseAngleButtonGroup.SelectedObject = app.InclinationButton;
            app.interplanetaryvarin ='i';
            app.AngleValueEditField.Value = app.i;
            Run_UseCase_01(app)
            
        end

        % Value changed function: AltitudeofPeriapsisEditField2
        function Numeric_AltitudeofPeriapsis_ValueChanged(app, event)
            app.zp = app.AltitudeofPeriapsisEditField2.Value;
            app.AltitudePeriapsisEditField.Value = app.zp

            Run_UseCase_01(app)
            
        end

        % Menu selected function: DevFx1_ExportValues
        function DevFx_ExportValuesMenuSelected(app, event)
            assignin('base', 'appVar01_Planet', app.Planet);  
            assignin('base', 'appVar02_mu'    , app.mu);  % Example of exposing a property to the base workspace
            assignin('base', 'appVar03_ROI'  , app.ROI); 
            assignin('base', 'appVar04_ra'   , app.rightasc   );
            assignin('base', 'appVar05_dec'  , app.declin);
            assignin('base', 'appVar06_BdotR', app.BdotR);
            assignin('base', 'appVar07_BdotT', app.BdotT);
            assignin('base', 'appVar08_BMag' , app.BMag);

            assignin('base', 'appVar10_a'   , app.a);
     
            assignin('base', 'appVar11_Ecc' , app.e);
            assignin('base', 'appVar12_Incl', app.i);
            assignin('base', 'appVar13_RAAN', app.RAAN);
            assignin('base', 'appVar14_AOP' , app.argp);
            assignin('base', 'appVar15_TA'  , app.TA)

            assignin('base', 'appVar20_velocity_GCI' , app.v);
            assignin('base', 'appVar21_r_entry_GCI'  , app.r_entry)
            assignin('base', 'appVar22_r_ECI_Arrival', app.r_ECI_arr_p)


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % update the Interplanetary Travel Calculate with new values
            clc,  cla(app.UIAxes); cla(app.UIAxes_2) ;

            Run_UseCase_01(app);
        end

        % Button pushed function: TestCase01Button
        function TestCase01ButtonPushed(app, event)
            Run_TestCase_1(app);
        end

        % Button pushed function: TestCase02Button
        function TestCase02ButtonPushed(app, event)
            % Run_TestCase_2(app);
        end

        % Button pushed function: TestCase03Button
        function TestCase03ButtonPushed(app, event)
            Run_TestCase_3(app);
        end

        % Button pushed function: TestCase04Button
        function TestCase04ButtonPushed(app, event)
            Run_TestCase_4(app);
        end

        % Selection changed function: ChooseAngleButtonGroup
        function ChooseAngleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseAngleButtonGroup.SelectedObject;
            if selectedButton == app.ArgumentofPeriapsisButton
                app.interplanetaryvarin ='ARGP';
                app.argp = app.AngleValueEditField.Value;
            elseif selectedButton == app.RightAscensionofAscendingNodeButton
                app.interplanetaryvarin ='RAAN';
                app.RAAN = app.AngleValueEditField.Value;
            elseif selectedButton == app.InclinationButton
                app.interplanetaryvarin ='i';
                app.i = app.AngleValueEditField.Value;
            else
                fprintf('Error Occured: Radio Button'), return
            end
% app.AngleValueEditField
            
        end

        % Callback function: not associated with a component
        function ExportFigure1MenuSelected(app, event)
            
                    % Create a new figure
                    tempFig = figure('Visible', 'off');  % Don't show the figure
                
                    % Copy the axes and its children to the new figure
                    newAxes = copyobj(app.UIAxes, tempFig);
                
                    % Adjust layout so it fits the new figure
                    set(newAxes, 'Units', 'normalized', 'Position', [0.13 0.11 0.775 0.815]);
                    % 
                    % Prompt user for file location
                    [file, path] = uiputfile('*.fig', 'Save Figure As');
                    if isequal(file, 0)
                        disp('User canceled save.');
                        close(tempFig);
                        return;
                    end
                
                    % Save the figure
                    savefig(tempFig, fullfile(path, file));
                
                    % Clean up
                    close(tempFig);


        end

        % Callback function: not associated with a component
        function ExportFigure2MenuSelected(app, event)
                                % Create a new figure
                    tempFig = figure('Visible', 'off');  % Don't show the figure
                
                    % Copy the axes and its children to the new figure
                    newAxes = copyobj(app.UIAxes_2, tempFig);
                
                    % Adjust layout so it fits the new figure
                    set(newAxes, 'Units', 'normalized', 'Position', [0.13 0.11 0.775 0.815]);
                    % 
                    % Prompt user for file location
                    [file, path] = uiputfile('*.fig', 'Save Figure As');
                    if isequal(file, 0)
                        disp('User canceled save.');
                        close(tempFig);
                        return;
                    end
                
                    % Save the figure
                    savefig(tempFig, fullfile(path, file));
                
                    % Clean up
                    close(tempFig);
        end

        % Callback function: not associated with a component
        function ExportFigure3MenuSelected(app, event)
                                % Create a new figure
                    tempFig = figure('Visible', 'off');  % Don't show the figure
                
                    % Copy the axes and its children to the new figure
                    newAxes = copyobj(app.UIAxes3, tempFig);
                
                    % Adjust layout so it fits the new figure
                    set(newAxes, 'Units', 'normalized', 'Position', [0.13 0.11 0.775 0.815]);
                    % 
                    % Prompt user for file location
                    [file, path] = uiputfile('*.fig', 'Save Figure As');
                    if isequal(file, 0)
                        disp('User canceled save.');
                        close(tempFig);
                        return;
                    end
                
                    % Save the figure
                    savefig(tempFig, fullfile(path, file));
                
                    % Clean up
                    close(tempFig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.DevFunctions = uimenu(app.Menu);
            app.DevFunctions.Text = 'Menu';
            app.DevFx1_ExportValues = uimenu(app.DevFunctions);
            app.DevFx1_ExportValues.MenuSelectedFcn = createCallbackFcn(app, @DevFx_ExportValuesMenuSelected, true);
            app.DevFx1_ExportValues.Text = 'Dev Function 1: Export Values';
            app.OrbitVisualizerLabel = uilabel(app.UIFigure);
            app.OrbitVisualizerLabel.Text = 'Orbit Visualizer';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterplanetaryTransferTab = uitab(app.TabGroup);
            app.InterplanetaryTransferTab.Title = '1. Interplanetary Transfer';
            app.UIAxes3 = uiaxes(app.InterplanetaryTransferTab);
            title(app.UIAxes3, 'Figure 3: Interplanetary')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.InterplanetaryTransferFunctionInputsPanel = uipanel(app.InterplanetaryTransferTab);
            app.InterplanetaryTransferFunctionInputsPanel.Title = 'Interplanetary Transfer Function Inputs';
            app.ArrivalPlanetDropDownLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.ArrivalPlanetDropDownLabel.Text = 'Arrival Planet ';
            app.ArrivalPlanetDropDown = uidropdown(app.InterplanetaryTransferFunctionInputsPanel);
            app.ArrivalPlanetDropDown.Items = {'Mercury', 'Venus', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.ArrivalPlanetDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDown_Planet_ValueChanged, true);
            app.ArrivalPlanetDropDown.Placeholder = 'Mars';
            app.ArrivalPlanetDropDown.Value = 'Mars';
            app.DeparturePlanetDropDownLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.DeparturePlanetDropDownLabel.Text = 'Departure Planet ';
            app.DeparturePlanetDropDown = uidropdown(app.InterplanetaryTransferFunctionInputsPanel);
            app.DeparturePlanetDropDown.Items = {'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.DeparturePlanetDropDown.Placeholder = 'Mars';
            app.DeparturePlanetDropDown.Value = 'Earth';
            app.TimeofFlightTOFEditFieldLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.TimeofFlightTOFEditFieldLabel.Text = 'Time-of-Flight, TOF';
            app.TimeofFlightTOFEditField = uieditfield(app.InterplanetaryTransferFunctionInputsPanel, 'numeric');
            app.TimeofFlightTOFEditField.Limits = [0 Inf];
            app.TimeofFlightTOFEditField.AllowEmpty = 'on';
            app.daysLabel_2 = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.daysLabel_2.Text = 'days';
            app.daysLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.daysLabel.Text = 'days';
            app.AdditionaldaysEditFieldLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.AdditionaldaysEditFieldLabel.Text = 'Additional days';
            app.AdditionaldaysEditField = uieditfield(app.InterplanetaryTransferFunctionInputsPanel, 'numeric');
            app.AdditionaldaysEditField.Limits = [0 Inf];
            app.AdditionaldaysEditField.AllowEmpty = 'on';
            app.AdditionaldaysEditField.Tooltip = {'Days added or subtracted to Launch Date'};
            app.AdditionaldaysEditField.Placeholder = '0';
            app.LaunchDateDatePickerLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.LaunchDateDatePickerLabel.Text = 'Launch Date';
            app.LaunchDateDatePicker = uidatepicker(app.InterplanetaryTransferFunctionInputsPanel);
            app.LaunchDateDatePicker.Value = datetime([2024 11 7]);
            app.CalculateButton = uibutton(app.InterplanetaryTransferFunctionInputsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AngleValueEditFieldLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.AngleValueEditFieldLabel.Text = 'Angle Value';
            app.AngleValueEditField = uieditfield(app.InterplanetaryTransferFunctionInputsPanel, 'numeric');
            app.AngleValueEditField.Limits = [-360 360];
            app.AngleValueEditField.AllowEmpty = 'on';
            app.AngleValueEditField.Tooltip = {''};
            app.AngleValueEditField.Placeholder = '0';
            app.degreesLabel_6 = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.degreesLabel_6.Text = 'degrees';
            app.kmLabel_2 = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.kmLabel_2.Text = 'km';
            app.AltitudePeriapsisEditFieldLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.AltitudePeriapsisEditFieldLabel.Text = 'Altitude @ Periapsis';
            app.AltitudePeriapsisEditField = uieditfield(app.InterplanetaryTransferFunctionInputsPanel, 'numeric');
            app.AltitudePeriapsisEditField.Limits = [0 Inf];
            app.AltitudePeriapsisEditField.AllowEmpty = 'on';
            app.AltitudePeriapsisEditField.Tooltip = {''};
            app.AltitudePeriapsisEditField.Placeholder = '0';
            app.ArrivalOrbitParametersLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.ArrivalOrbitParametersLabel.Text = 'Arrival Orbit Parameters';
            app.ChooseAngleButtonGroup = uibuttongroup(app.InterplanetaryTransferFunctionInputsPanel);
            app.ChooseAngleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseAngleButtonGroupSelectionChanged, true);
            app.ChooseAngleButtonGroup.Tooltip = {'Since the altitude at periapsis is choosen, only one angle may be specified.'};
            app.ChooseAngleButtonGroup.Title = 'Choose Angle:';
            app.InclinationButton = uiradiobutton(app.ChooseAngleButtonGroup);
            app.InclinationButton.Text = 'Inclination';
            app.InclinationButton.Value = true;
            app.RightAscensionofAscendingNodeButton = uiradiobutton(app.ChooseAngleButtonGroup);
            app.RightAscensionofAscendingNodeButton.Text = {'Right Ascension '; 'of Ascending Node'};
            app.ArgumentofPeriapsisButton = uiradiobutton(app.ChooseAngleButtonGroup);
            app.ArgumentofPeriapsisButton.Text = 'Argument of Periapsis';
            app.ROmaxArgp = uieditfield(app.InterplanetaryTransferFunctionInputsPanel, 'numeric');
            app.ROmaxArgp.Editable = 'off';
            app.ROminArgp = uieditfield(app.InterplanetaryTransferFunctionInputsPanel, 'numeric');
            app.ROminArgp.Editable = 'off';
            app.ROmaxIncl = uieditfield(app.InterplanetaryTransferFunctionInputsPanel, 'numeric');
            app.ROmaxIncl.Editable = 'off';
            app.ROminIncl = uieditfield(app.InterplanetaryTransferFunctionInputsPanel, 'numeric');
            app.ROminIncl.Editable = 'off';
            app.MinLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.MinLabel.Text = 'Min.';
            app.MaxLabel = uilabel(app.InterplanetaryTransferFunctionInputsPanel);
            app.MaxLabel.Text = 'Max.';
            app.ArrivalOrbitTab = uitab(app.TabGroup);
            app.ArrivalOrbitTab.Title = '2. Arrival Orbit';
            app.UIAxes = uiaxes(app.ArrivalOrbitTab);
            title(app.UIAxes, 'Figure 1: "Far View" Spacecraft Entering ROI')
            xlabel(app.UIAxes, 'X [km]')
            ylabel(app.UIAxes, 'Y [km]')
            zlabel(app.UIAxes, 'Z [km]')
            app.UIAxes_2 = uiaxes(app.ArrivalOrbitTab);
            title(app.UIAxes_2, 'Figure 2: "Near View" ')
            xlabel(app.UIAxes_2, 'X [km]')
            ylabel(app.UIAxes_2, 'Y [km]')
            zlabel(app.UIAxes_2, 'Z [km]')
            app.ModifySphereofInfluenceEntryLocationPanel = uipanel(app.ArrivalOrbitTab);
            app.ModifySphereofInfluenceEntryLocationPanel.Title = 'Modify Sphere of Influence Entry Location';
            app.RightAscSliderLabel = uilabel(app.ModifySphereofInfluenceEntryLocationPanel);
            app.RightAscSliderLabel.Text = 'Right Asc.';
            app.RightAscSlider = uislider(app.ModifySphereofInfluenceEntryLocationPanel);
            app.RightAscSlider.Limits = [-0.02 0.02];
            app.RightAscSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_RightAsc_ValueChanged, true);
            app.RightAscSlider.Tooltip = {'Geocentric Right ascension (RA or α) is measured along the celestial equator in degrees east from the vernal equinox . '; 'Not the same as the Right Ascension of the Ascending Node (RAAN)'};
            app.DeclinationSliderLabel = uilabel(app.ModifySphereofInfluenceEntryLocationPanel);
            app.DeclinationSliderLabel.Text = 'Declination';
            app.DeclinationSlider = uislider(app.ModifySphereofInfluenceEntryLocationPanel);
            app.DeclinationSlider.Limits = [-0.03 0.03];
            app.DeclinationSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Declination_ValueChanged, true);
            app.DeclinationSlider.Tooltip = {'Declination (Dec or δ) is measured along a meridian in degrees, positive to the north of the equator and negative to the south.'};
            app.degEditFieldLabel = uilabel(app.ModifySphereofInfluenceEntryLocationPanel);
            app.degEditFieldLabel.Text = 'deg';
            app.degRightAsc = uieditfield(app.ModifySphereofInfluenceEntryLocationPanel, 'numeric');
            app.degRightAsc.Editable = 'off';
            app.degRightAsc.Tooltip = {'Current Value'};
            app.degEditField_2Label = uilabel(app.ModifySphereofInfluenceEntryLocationPanel);
            app.degEditField_2Label.Text = 'deg';
            app.degDeclin = uieditfield(app.ModifySphereofInfluenceEntryLocationPanel, 'numeric');
            app.degDeclin.AllowEmpty = 'on';
            app.degDeclin.Editable = 'off';
            app.degDeclin.Tooltip = {'Current Value'};
            app.AdjustValuebypercentLabel_2 = uilabel(app.ModifySphereofInfluenceEntryLocationPanel);
            app.AdjustValuebypercentLabel_2.Text = 'Adjust Value, by percent';
            app.ModifyVelocityAngelsPanel = uipanel(app.ArrivalOrbitTab);
            app.ModifyVelocityAngelsPanel.Title = 'Modify Velocity Angels';
            app.AdjustValuebypercentLabel = uilabel(app.ModifyVelocityAngelsPanel);
            app.AdjustValuebypercentLabel.Text = 'Adjust Value, by percent';
            app.El_Label = uilabel(app.ModifyVelocityAngelsPanel);
            app.El_Label.Text = 'Elevation, el';
            app.El_Slider = uislider(app.ModifyVelocityAngelsPanel);
            app.El_Slider.Limits = [-0.02 0.02];
            app.El_Slider.ValueChangedFcn = createCallbackFcn(app, @Slider_Elevation_ValueChanged, true);
            app.Az_betaSlider = uislider(app.ModifyVelocityAngelsPanel);
            app.Az_betaSlider.Limits = [-0.02 0.02];
            app.Az_betaSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Azimuth_ValueChanged, true);
            app.Az_betaSliderLabel = uilabel(app.ModifyVelocityAngelsPanel);
            app.Az_betaSliderLabel.Text = 'Azimuth, beta';
            app.degEditFieldLabel_2 = uilabel(app.ModifyVelocityAngelsPanel);
            app.degEditFieldLabel_2.Text = 'deg';
            app.degEl_el = uieditfield(app.ModifyVelocityAngelsPanel, 'numeric');
            app.degEl_el.Editable = 'off';
            app.degEl_el.Tooltip = {'Current Value'};
            app.degEditField_2Label_2 = uilabel(app.ModifyVelocityAngelsPanel);
            app.degEditField_2Label_2.Text = 'deg';
            app.degAz_beta = uieditfield(app.ModifyVelocityAngelsPanel, 'numeric');
            app.degAz_beta.AllowEmpty = 'on';
            app.degAz_beta.Editable = 'off';
            app.degAz_beta.Tooltip = {'Current Value'};
            app.ControlVariablesPanel = uipanel(app.ArrivalOrbitTab);
            app.ControlVariablesPanel.Title = 'Control Variables';
            app.InclinationEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.ControlVariablesPanel, 'numeric');
            app.InclinationEditField.Limits = [-360 360];
            app.InclinationEditField.AllowEmpty = 'on';
            app.InclinationEditField.ValueChangedFcn = createCallbackFcn(app, @Numeric_Inclination_ValueChanged, true);
            app.AltitudeofPeriapsisEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.AltitudeofPeriapsisEditFieldLabel.Text = 'Altitude of Periapsis';
            app.AltitudeofPeriapsisEditField2 = uieditfield(app.ControlVariablesPanel, 'numeric');
            app.AltitudeofPeriapsisEditField2.AllowEmpty = 'on';
            app.AltitudeofPeriapsisEditField2.ValueChangedFcn = createCallbackFcn(app, @Numeric_AltitudeofPeriapsis_ValueChanged, true);
            app.degLabel_3 = uilabel(app.ControlVariablesPanel);
            app.degLabel_3.Text = 'deg';
            app.kmLabel = uilabel(app.ControlVariablesPanel);
            app.kmLabel.Text = 'km';
            app.degLabel_2 = uilabel(app.ControlVariablesPanel);
            app.degLabel_2.Text = 'deg';
            app.RAANEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.RAANEditFieldLabel.Tooltip = {'Right Ascension of the Ascending Node'};
            app.RAANEditFieldLabel.Text = 'RAAN';
            app.RAANEditField = uieditfield(app.ControlVariablesPanel, 'numeric');
            app.RAANEditField.Limits = [0 360];
            app.RAANEditField.AllowEmpty = 'on';
            app.degLabel = uilabel(app.ControlVariablesPanel);
            app.degLabel.Text = 'deg';
            app.ArgumentofPerigeewEditFieldLabel = uilabel(app.ControlVariablesPanel);
            app.ArgumentofPerigeewEditFieldLabel.Text = 'Argument of Perigee, w';
            app.ArgumentofPerigeewEditField = uieditfield(app.ControlVariablesPanel, 'numeric');
            app.ArgumentofPerigeewEditField.Limits = [0 360];
            app.ArgumentofPerigeewEditField.AllowEmpty = 'on';
            app.ReadoutPanel = uipanel(app.ArrivalOrbitTab);
            app.ReadoutPanel.Title = 'Readout';
            app.km2sLabel = uilabel(app.ReadoutPanel);
            app.km2sLabel.Interpreter = 'latex';
            app.km2sLabel.Text = 'km^2/s';
            app.degLabel_4 = uilabel(app.ReadoutPanel);
            app.degLabel_4.Text = 'deg';
            app.AngularMomentumEditFieldLabel = uilabel(app.ReadoutPanel);
            app.AngularMomentumEditFieldLabel.Text = 'Angular Momentum';
            app.AngularMomentumEditField = uieditfield(app.ReadoutPanel, 'numeric');
            app.AngularMomentumEditField.ValueDisplayFormat = '%3.2e';
            app.AngularMomentumEditField.AllowEmpty = 'on';
            app.AngularMomentumEditField.Editable = 'off';
            app.TrueAnomalyEditFieldLabel = uilabel(app.ReadoutPanel);
            app.TrueAnomalyEditFieldLabel.Text = 'True Anomaly';
            app.TrueAnomalyEditField = uieditfield(app.ReadoutPanel, 'numeric');
            app.TrueAnomalyEditField.Limits = [-360 360];
            app.TrueAnomalyEditField.AllowEmpty = 'on';
            app.TrueAnomalyEditField.Editable = 'off';
            app.EccentricityEditFieldLabel = uilabel(app.ReadoutPanel);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.ReadoutPanel, 'numeric');
            app.EccentricityEditField.AllowEmpty = 'on';
            app.EccentricityEditField.Editable = 'off';
            app.ScratchTab = uitab(app.TabGroup);
            app.ScratchTab.Title = '99. Scratch';
            app.ModifyMagnitudeOfVelocityplanetcentricbypercentLabel = uilabel(app.ScratchTab);
            app.ModifyMagnitudeOfVelocityplanetcentricbypercentLabel.Text = 'Modify Magnitude OfVelocity (planet-centric), by percent';
            app.VelocityMagSliderLabel = uilabel(app.ScratchTab);
            app.VelocityMagSliderLabel.Text = 'Velocity, Mag';
            app.VelocityMagSlider = uislider(app.ScratchTab);
            app.VelocityMagSlider.Limits = [-0.5 0.5];
            app.VelocityMagSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_VelocityMag_ValueChanged, true);
            app.kmsEditFieldLabel = uilabel(app.ScratchTab);
            app.kmsEditFieldLabel.Text = 'km/s';
            app.kmsVelocityMag = uieditfield(app.ScratchTab, 'numeric');
            app.kmsVelocityMag.Editable = 'off';
            app.kmsVelocityMag.Value = -0.1;
            app.TestCase04Button = uibutton(app.ScratchTab, 'push');
            app.TestCase04Button.ButtonPushedFcn = createCallbackFcn(app, @TestCase04ButtonPushed, true);
            app.TestCase04Button.Text = 'Test Case 04';
            app.TestCase03Button = uibutton(app.ScratchTab, 'push');
            app.TestCase03Button.ButtonPushedFcn = createCallbackFcn(app, @TestCase03ButtonPushed, true);
            app.TestCase03Button.Text = 'Test Case 03';
            app.TestCase02Button = uibutton(app.ScratchTab, 'push');
            app.TestCase02Button.ButtonPushedFcn = createCallbackFcn(app, @TestCase02ButtonPushed, true);
            app.TestCase02Button.Text = 'Test Case 02';
            app.TestCase01Button = uibutton(app.ScratchTab, 'push');
            app.TestCase01Button.ButtonPushedFcn = createCallbackFcn(app, @TestCase01ButtonPushed, true);
            app.TestCase01Button.Text = 'Test Case 01';
            app.SampleHyperbolicOrbitLabel = uilabel(app.ScratchTab);
            app.SampleHyperbolicOrbitLabel.Text = 'Sample Hyperbolic Orbit';
            app.InterPlanetaryTravelLabel = uilabel(app.ScratchTab);
            app.InterPlanetaryTravelLabel.Text = 'InterPlanetary Travel';
            app.VelocitykmsPlanetcentricLabel = uilabel(app.ScratchTab);
            app.VelocitykmsPlanetcentricLabel.Text = 'Velocity - km/s. Planet-centric.';
            app.V_infEditFieldLabel = uilabel(app.ScratchTab);
            app.V_infEditFieldLabel.Text = 'V_inf';
            app.V_infEditField = uieditfield(app.ScratchTab, 'numeric');
            app.V_infEditField.Limits = [0 Inf];
            app.V_infEditField.AllowEmpty = 'on';
            app.V_infEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.ScratchTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ScratchTab, 'numeric');
            app.YEditField.Editable = 'off';
            app.YEditField.Value = -0.5;
            app.ZEditFieldLabel = uilabel(app.ScratchTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ScratchTab, 'numeric');
            app.ZEditField.Editable = 'off';
            app.ZEditField.Value = -0.1;
            app.XEditFieldLabel = uilabel(app.ScratchTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ScratchTab, 'numeric');
            app.XEditField.Editable = 'off';
            app.XEditField.Value = -0.1;
            app.kmsLabel = uilabel(app.ScratchTab);
            app.kmsLabel.Text = 'km/s';
            app.ROmaxRAAN = uieditfield(app.ScratchTab, 'numeric');
            app.ROmaxRAAN.Editable = 'off';
            app.ROminRAAN = uieditfield(app.ScratchTab, 'numeric');
            app.ROminRAAN.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BPlaneVisualizationRev09d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end